classdef pdfplot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        AnalyseexperimentsTab           matlab.ui.container.Tab
        selectinputdirectoryButton      matlab.ui.control.StateButton
        AnalyseButton                   matlab.ui.control.Button
        outputnameEditFieldLabel        matlab.ui.control.Label
        outputnameEditField             matlab.ui.control.EditField
        filterbywingextensionframesPanel  matlab.ui.container.Panel
        useonlywingextensionframesCheckBox  matlab.ui.control.CheckBox
        minwingextensionangledegreesEditFieldLabel  matlab.ui.control.Label
        minwingextensionangledegreesEditField  matlab.ui.control.NumericEditField
        minwingextensiondurframesEditFieldLabel  matlab.ui.control.Label
        minwingextensiondurframesEditField  matlab.ui.control.NumericEditField
        removecopulationframesCheckBox  matlab.ui.control.CheckBox
        FeatureselectionPanel           matlab.ui.container.Panel
        scalingfactorEditFieldLabel     matlab.ui.control.Label
        scalingfactorEditField          matlab.ui.control.NumericEditField
        FeaturetoanalyseLabel           matlab.ui.control.Label
        feature_dropdown                matlab.ui.control.DropDown
        FilteringdataPanel              matlab.ui.container.Panel
        FilterbyfeatureLabel            matlab.ui.control.Label
        feature_dropdown_filter         matlab.ui.control.DropDown
        FilterbyJAABAscoreLabel         matlab.ui.control.Label
        score_dropdown                  matlab.ui.control.DropDown
        movingaveragewindowsizeEditFieldLabel  matlab.ui.control.Label
        movingaveragewindowsizeEditField  matlab.ui.control.NumericEditField
        cutofffractionEditFieldLabel    matlab.ui.control.Label
        cutofffractionEditField         matlab.ui.control.NumericEditField
        cutoffvalueEditFieldLabel       matlab.ui.control.Label
        cutoffvalueEditField            matlab.ui.control.NumericEditField
        selectframesabovecutoffvalueCheckBox  matlab.ui.control.CheckBox
        AverageexperimentsTab           matlab.ui.container.Tab
        selectgenotypelistButton        matlab.ui.control.Button
        fileprefixegyourgenotypeLabel   matlab.ui.control.Label
        fileprefixegyourgenotypeEditField  matlab.ui.control.EditField
        EditFieldLabel                  matlab.ui.control.Label
        EditField                       matlab.ui.control.EditField
        experimentnamemustmatchoutputnameofstep1Label  matlab.ui.control.Label
        asenteredinanalyseexperimentsasoutputnameLabel  matlab.ui.control.Label
        AverageButton                   matlab.ui.control.Button
    end


    properties (Access = public)
        column=1; % feature column to be analysed
        outputname % Description
        scaling % scaling factor for the output of the feature (default 1)
        wingextonly % Description
        filtercolumn % filtering by this column
        filterscore % Description
        fromscores % Boolean: filter by a value from JAABA scores
        cutoffval % value above or below which feature needs to be to be filtered
        above % Boolean: above or below cutoffval
        cutofffrac % Fraction of frames in moving average window that have to be positive for score
        windowsize % Size of moving average window
        wingdur % minimum number of consecutive wing extension frames
        minwingangle % minimum angle to be counted as wing extension
        inputdir % Description
        genotypelist% Description
        outputname_avg% Description
        expname % Description
        removecop % Description
    end

    properties (Access = private)
       
        Property16 % Description
        path % Description
        Property20 % Description
    end

    methods (Access = private)
    
        function results = run_pdfplots_helper(app)
            
            feature_selection(app);
            set_scaling(app);
            set_wingextensiononly_status(app);
            filter_selection(app);
            filter_score_selection(app);
            set_cutoff(app);
            set_above(app);
            set_windowsize(app);
            set_cutofffrac(app);
            set_wingdur(app);
            set_minwingangle(app);
            set_removecopulationframes(app);
            run_pdfplots_any(app.outputname, app.column, 'scaling',app.scaling,'wingdur',app.wingdur,'wingextonly',app.wingextonly,'minwingangle',app.minwingangle,'fromscores',app.fromscores,'windowsize',app.windowsize,'cutofffrac',app.cutofffrac,'score',app.filterscore,'specificframes',false,'filterby',app.filtercolumn,'cutoffval',app.cutoffval,'above',app.above,'removecop',app.removecop);
            results = 'success';
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: selectinputdirectoryButton
        function select_dir(app, event)
            
            app.inputdir=uigetdir();
             cd (char(app.inputdir));
        end

        % Value changed function: feature_dropdown
        function feature_selection(app, event)
           selected_feature = app.feature_dropdown.Value;
           disp('selected feature:');
           disp(selected_feature);
           columnnumber = regexpi(selected_feature,'\d+','match');
           disp(columnnumber);
          % disp(app.column);
           app.column = int8(str2double(columnnumber)); 
           disp(app.column);
        end

        % Callback function: outputnameEditField, outputnameEditField
        function set_outputname(app, event)
            app.outputname = event.Value;
           % disp(outputname);
            
        end

        % Value changed function: scalingfactorEditField
        function set_scaling(app, event)
            app.scaling = app.scalingfactorEditField.Value;
            
        end

        % Value changed function: useonlywingextensionframesCheckBox
        function set_wingextensiononly_status(app, event)
            app.wingextonly = app.useonlywingextensionframesCheckBox.Value;
            %disp(app.wingextonly);
        end

        % Value changed function: feature_dropdown_filter
        function filter_selection(app, event)
            selected_feature = app.feature_dropdown_filter.Value;
            disp('filter by feature:');
           disp(selected_feature);
           columnnumber = regexpi(selected_feature,'\d','match');
           %disp(columnnumber);
           %disp(app.filtercolumn);
           app.filtercolumn = str2double(columnnumber); 
           %disp(app.filtercolumn);
        end

        % Value changed function: score_dropdown
        function filter_score_selection(app, event)
            selected_score = app.score_dropdown.Value;
            if contains(selected_score, 'none')
                app.fromscores =false;
            else
                app.fromscores= true;
           
            disp('filter by JAABA score:');
           disp(selected_score);
         app.filterscore = selected_score;
           %disp(app.filterscore);
            end
            disp('fromscores set to:');
            disp(app.fromscores);
        end

        % Value changed function: cutoffvalueEditField
        function set_cutoff(app, event)
            app.cutoffval = app.cutoffvalueEditField.Value;
            
        end

        % Value changed function: 
        % selectframesabovecutoffvalueCheckBox
        function set_above(app, event)
            app.above = app.selectframesabovecutoffvalueCheckBox.Value;
            
        end

        % Value changed function: movingaveragewindowsizeEditField
        function set_windowsize(app, event)
            app.windowsize = app.movingaveragewindowsizeEditField.Value;
            
        end

        % Value changed function: cutofffractionEditField
        function set_cutofffrac(app, event)
            app.cutofffrac = app.cutofffractionEditField.Value;
            
        end

        % Button pushed function: AnalyseButton
        function run_pdfplots_app(app, event)
            position = app.AnalyseexperimentsTab.Position;
           
           
            result=run_pdfplots_helper(app);
            disp(result);
        end

        % Value changed function: minwingextensiondurframesEditField
        function set_wingdur(app, event)
            app.wingdur = app.minwingextensiondurframesEditField.Value;
            
        end

        % Value changed function: 
        % minwingextensionangledegreesEditField
        function set_minwingangle(app, event)
            app.minwingangle = app.minwingextensionangledegreesEditField.Value;
            
        end

        % Button pushed function: selectgenotypelistButton
        function select_genotypelist(app, event)
            position = app.AverageexperimentsTab.Position;
            [app.genotypelist,app.path] = uigetfile('*.xlsx','Select genotypelist');
            cd(app.path);
            
             
        end

        % Value changed function: fileprefixegyourgenotypeEditField
        function set_output_name_avg(app, event)
            app.outputname_avg = app.fileprefixegyourgenotypeEditField.Value;
            
        end

        % Value changed function: EditField
        function set_experiment_name(app, event)
            app.expname = app.EditField.Value;
            
        end

        % Button pushed function: AverageButton
        function average(app, event)
            find_videos_new(app.genotypelist,'pdfs',app.expname,'pdfdata',app.outputname_avg);
        end

        % Value changed function: removecopulationframesCheckBox
        function set_removecopulationframes(app, event)
            app.removecop = app.removecopulationframesCheckBox.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AnalyseexperimentsTab = uitab(app.TabGroup);
            app.AnalyseexperimentsTab.Title = 'Analyse experiments';
            app.selectinputdirectoryButton = uibutton(app.AnalyseexperimentsTab, 'state');
            app.selectinputdirectoryButton.ValueChangedFcn = createCallbackFcn(app, @select_dir, true);
            app.selectinputdirectoryButton.Text = 'select input directory';
            app.AnalyseButton = uibutton(app.AnalyseexperimentsTab, 'push');
            app.AnalyseButton.ButtonPushedFcn = createCallbackFcn(app, @run_pdfplots_app, true);
            app.AnalyseButton.Text = 'Analyse';
            app.outputnameEditFieldLabel = uilabel(app.AnalyseexperimentsTab);
            app.outputnameEditFieldLabel.Text = 'outputname';
            app.outputnameEditField = uieditfield(app.AnalyseexperimentsTab, 'text');
            app.outputnameEditField.ValueChangedFcn = createCallbackFcn(app, @set_outputname, true);
            app.outputnameEditField.ValueChangingFcn = createCallbackFcn(app, @set_outputname, true);
            app.outputnameEditField.Value = 'feature';
            app.filterbywingextensionframesPanel = uipanel(app.AnalyseexperimentsTab);
            app.filterbywingextensionframesPanel.Title = 'filter by wingextension frames';
            app.useonlywingextensionframesCheckBox = uicheckbox(app.filterbywingextensionframesPanel);
            app.useonlywingextensionframesCheckBox.ValueChangedFcn = createCallbackFcn(app, @set_wingextensiononly_status, true);
            app.useonlywingextensionframesCheckBox.Text = 'use only wingextension frames';
            app.useonlywingextensionframesCheckBox.Value = true;
            app.minwingextensionangledegreesEditFieldLabel = uilabel(app.filterbywingextensionframesPanel);
            app.minwingextensionangledegreesEditFieldLabel.Text = 'min wing extension angle (degrees)';
            app.minwingextensionangledegreesEditField = uieditfield(app.filterbywingextensionframesPanel, 'numeric');
            app.minwingextensionangledegreesEditField.ValueChangedFcn = createCallbackFcn(app, @set_minwingangle, true);
            app.minwingextensionangledegreesEditField.Value = 30;
            app.minwingextensiondurframesEditFieldLabel = uilabel(app.filterbywingextensionframesPanel);
            app.minwingextensiondurframesEditFieldLabel.Text = 'min wing extension dur (frames)';
            app.minwingextensiondurframesEditField = uieditfield(app.filterbywingextensionframesPanel, 'numeric');
            app.minwingextensiondurframesEditField.ValueChangedFcn = createCallbackFcn(app, @set_wingdur, true);
            app.minwingextensiondurframesEditField.Value = 13;
            app.removecopulationframesCheckBox = uicheckbox(app.filterbywingextensionframesPanel);
            app.removecopulationframesCheckBox.ValueChangedFcn = createCallbackFcn(app, @set_removecopulationframes, true);
            app.removecopulationframesCheckBox.Text = 'remove copulation frames';
            app.removecopulationframesCheckBox.Value = true;
            app.FeatureselectionPanel = uipanel(app.AnalyseexperimentsTab);
            app.FeatureselectionPanel.Title = 'Feature selection';
            app.scalingfactorEditFieldLabel = uilabel(app.FeatureselectionPanel);
            app.scalingfactorEditFieldLabel.Text = 'scaling factor';
            app.scalingfactorEditField = uieditfield(app.FeatureselectionPanel, 'numeric');
            app.scalingfactorEditField.ValueChangedFcn = createCallbackFcn(app, @set_scaling, true);
            app.scalingfactorEditField.Value = 1;
            app.FeaturetoanalyseLabel = uilabel(app.FeatureselectionPanel);
            app.FeaturetoanalyseLabel.Text = 'Feature to analyse';
            app.feature_dropdown = uidropdown(app.FeatureselectionPanel);
            app.feature_dropdown.Items = {'velocity (1)', 'angular velocity (2)', 'minimum wing angle (3)', 'maximum wing angle (4)', 'mean wing length (5)', 'axis ratio (6)', 'foreground body ratio (7)', 'contrast (8)', 'distance to wall (9)', 'distance to other (10)', 'angle between (11)', 'facing angle (12)', 'leg distance (13)'};
            app.feature_dropdown.ValueChangedFcn = createCallbackFcn(app, @feature_selection, true);
            app.feature_dropdown.Value = 'velocity (1)';
            app.FilteringdataPanel = uipanel(app.AnalyseexperimentsTab);
            app.FilteringdataPanel.Title = 'Filtering data';
            app.FilterbyfeatureLabel = uilabel(app.FilteringdataPanel);
            app.FilterbyfeatureLabel.Text = 'Filter by feature';
            app.feature_dropdown_filter = uidropdown(app.FilteringdataPanel);
            app.feature_dropdown_filter.Items = {'none (0)', 'velocity (1)', 'angular velocity (2)', 'minimum wing angle (3)', 'maximum wing angle (4)', 'mean wing length (5)', 'axis ratio (6)', 'foreground body ratio (7)', 'contrast (8)', 'distance to wall (9)', 'distance to other (10)', 'angle between (11)', 'facing angle (12)', 'leg distance (13)'};
            app.feature_dropdown_filter.ValueChangedFcn = createCallbackFcn(app, @filter_selection, true);
            app.feature_dropdown_filter.Value = 'none (0)';
            app.FilterbyJAABAscoreLabel = uilabel(app.FilteringdataPanel);
            app.FilterbyJAABAscoreLabel.Text = 'Filter by JAABA score';
            app.score_dropdown = uidropdown(app.FilteringdataPanel);
            app.score_dropdown.Items = {'none (0)', 'Approaching', 'Contact', 'Copulation', 'Encircling', 'Facing', 'Turning', 'WingGesture'};
            app.score_dropdown.ValueChangedFcn = createCallbackFcn(app, @filter_score_selection, true);
            app.score_dropdown.Value = 'none (0)';
            app.movingaveragewindowsizeEditFieldLabel = uilabel(app.FilteringdataPanel);
            app.movingaveragewindowsizeEditFieldLabel.Text = 'moving average window size';
            app.movingaveragewindowsizeEditField = uieditfield(app.FilteringdataPanel, 'numeric');
            app.movingaveragewindowsizeEditField.ValueChangedFcn = createCallbackFcn(app, @set_windowsize, true);
            app.movingaveragewindowsizeEditField.Value = 10;
            app.cutofffractionEditFieldLabel = uilabel(app.FilteringdataPanel);
            app.cutofffractionEditFieldLabel.Text = 'cutoff fraction';
            app.cutofffractionEditField = uieditfield(app.FilteringdataPanel, 'numeric');
            app.cutofffractionEditField.ValueChangedFcn = createCallbackFcn(app, @set_cutofffrac, true);
            app.cutofffractionEditField.Value = 0.5;
            app.cutoffvalueEditFieldLabel = uilabel(app.FilteringdataPanel);
            app.cutoffvalueEditFieldLabel.Text = 'cutoff value';
            app.cutoffvalueEditField = uieditfield(app.FilteringdataPanel, 'numeric');
            app.cutoffvalueEditField.ValueChangedFcn = createCallbackFcn(app, @set_cutoff, true);
            app.cutoffvalueEditField.Value = 2;
            app.selectframesabovecutoffvalueCheckBox = uicheckbox(app.FilteringdataPanel);
            app.selectframesabovecutoffvalueCheckBox.ValueChangedFcn = createCallbackFcn(app, @set_above, true);
            app.selectframesabovecutoffvalueCheckBox.Text = 'select frames above cutoff value';
            app.selectframesabovecutoffvalueCheckBox.Value = true;
            app.AverageexperimentsTab = uitab(app.TabGroup);
            app.AverageexperimentsTab.Title = 'Average experiments';
            app.selectgenotypelistButton = uibutton(app.AverageexperimentsTab, 'push');
            app.selectgenotypelistButton.ButtonPushedFcn = createCallbackFcn(app, @select_genotypelist, true);
            app.selectgenotypelistButton.Text = 'select genotype list';
            app.fileprefixegyourgenotypeLabel = uilabel(app.AverageexperimentsTab);
            app.fileprefixegyourgenotypeLabel.Text = 'file prefix (e.g. your genotype)';
            app.fileprefixegyourgenotypeEditField = uieditfield(app.AverageexperimentsTab, 'text');
            app.fileprefixegyourgenotypeEditField.ValueChangedFcn = createCallbackFcn(app, @set_output_name_avg, true);
            app.EditFieldLabel = uilabel(app.AverageexperimentsTab);
            app.EditFieldLabel.Text = '';
            app.EditField = uieditfield(app.AverageexperimentsTab, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @set_experiment_name, true);
            app.experimentnamemustmatchoutputnameofstep1Label = uilabel(app.AverageexperimentsTab);
            app.experimentnamemustmatchoutputnameofstep1Label.Text = 'experiment name (must match outputname of step 1)';
            app.asenteredinanalyseexperimentsasoutputnameLabel = uilabel(app.AverageexperimentsTab);
            app.asenteredinanalyseexperimentsasoutputnameLabel.Text = '(as entered in analyse experiments as outputname)';
            app.AverageButton = uibutton(app.AverageexperimentsTab, 'push');
            app.AverageButton.ButtonPushedFcn = createCallbackFcn(app, @average, true);
            app.AverageButton.Text = 'Average';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pdfplot

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end